classdef ContrastChangeCylinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContrastChangeCorridorUIFigure  matlab.ui.Figure
        SelectTextureRNRButtonGroup     matlab.ui.container.ButtonGroup
        TexturesLamp                    matlab.ui.control.Lamp
        GRAYButton                      matlab.ui.control.RadioButton
        TilesLeavesButton               matlab.ui.control.RadioButton
        LeavesTilesButton               matlab.ui.control.RadioButton
        SquaresTilesButton              matlab.ui.control.RadioButton
        CirclesLeavesButton             matlab.ui.control.RadioButton
        TilesSquaresButton              matlab.ui.control.RadioButton
        LeavesCirclesButton             matlab.ui.control.RadioButton
        UploadTexturesButton            matlab.ui.control.Button
        SavingLabel                     matlab.ui.control.Label
        SessionButtonGroup              matlab.ui.container.ButtonGroup
        SessionNewexemplarcatchButton   matlab.ui.control.RadioButton
        SessionNewexemplarButton        matlab.ui.control.RadioButton
        SessionTrainingButton           matlab.ui.control.RadioButton
        RoomButtonGroup                 matlab.ui.container.ButtonGroup
        BergamoButton                   matlab.ui.control.RadioButton
        MesoscopeLabelDescrip           matlab.ui.control.Label
        RecordyesCheckBox               matlab.ui.control.CheckBox
        MesoscopeButton                 matlab.ui.control.RadioButton
        TrainingButton                  matlab.ui.control.RadioButton
        MonitoringPanel                 matlab.ui.container.Panel
        WaterDelivered                  matlab.ui.control.Label
        WaterdeliveredLabel             matlab.ui.control.Label
        TotalElapsedTimeVal             matlab.ui.control.Label
        TotalelapsedtimeLabel           matlab.ui.control.Label
        CurrentTrial                    matlab.ui.control.Label
        TrialLabel                      matlab.ui.control.Label
        IsImageRewarded                 matlab.ui.control.Label
        ImageLabel                      matlab.ui.control.Label
        CurrentRewardml                 matlab.ui.control.Label
        RewardsgivenLabel               matlab.ui.control.Label
        CurrentDistance                 matlab.ui.control.Label
        CurrentpositionLabel            matlab.ui.control.Label
        TrainingPanel                   matlab.ui.container.Panel
        dDistanceSpinner                matlab.ui.control.Spinner
        dDistanceSpinnerLabel           matlab.ui.control.Label
        AutorewardRNDLabel              matlab.ui.control.Label
        rewardtrainCheckBox             matlab.ui.control.CheckBox
        RewardnewtextureCheckBox        matlab.ui.control.CheckBox
        Autostop60minCheckBox           matlab.ui.control.CheckBox
        AutomovementCheckBox            matlab.ui.control.CheckBox
        AutorewardtestCheckBox          matlab.ui.control.CheckBox
        ManualRewardButton              matlab.ui.control.Button
        AnimalInformationPanel          matlab.ui.container.Panel
        LampID                          matlab.ui.control.Lamp
        SavePathLabel                   matlab.ui.control.Label
        SetIDButton                     matlab.ui.control.Button
        SavedatatoButton                matlab.ui.control.Button
        DateLabelValue                  matlab.ui.control.Label
        DateLabel                       matlab.ui.control.Label
        AnimalIDEditField               matlab.ui.control.EditField
        AnimalIDEditFieldLabel          matlab.ui.control.Label
        ExperimentSetupPanel            matlab.ui.container.Panel
        RewardObjVal                    matlab.ui.control.NumericEditField
        RewardObjlabel                  matlab.ui.control.Label
        ContraststepsEditField          matlab.ui.control.NumericEditField
        ContraststepsEditFieldLabel     matlab.ui.control.Label
        ImagelengthEditField            matlab.ui.control.NumericEditField
        ImagelengthLabel                matlab.ui.control.Label
        NoTrialsEditField               matlab.ui.control.NumericEditField
        NoTrialsEditFieldLabel          matlab.ui.control.Label
        PropNewEditField                matlab.ui.control.NumericEditField
        PropNewEditFieldLabel           matlab.ui.control.Label
        PropCatchEditField              matlab.ui.control.NumericEditField
        PropCatchEditFieldLabel         matlab.ui.control.Label
        PropRewardEditField             matlab.ui.control.NumericEditField
        PropRewardEditFieldLabel        matlab.ui.control.Label
        RewardSizeEditField             matlab.ui.control.NumericEditField
        SizemsLabel                     matlab.ui.control.Label
        RewardLabel                     matlab.ui.control.Label
        LampExperiment                  matlab.ui.control.Lamp
        SetExperimentButton             matlab.ui.control.Button
        HigherEditField                 matlab.ui.control.NumericEditField
        HigherEditFieldLabel            matlab.ui.control.Label
        LowerEditField                  matlab.ui.control.NumericEditField
        LowerEditFieldLabel             matlab.ui.control.Label
        VelocityTresholdsLabel          matlab.ui.control.Label
        SavePathValueLabel              matlab.ui.control.Label
        STOPSAVEButton                  matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        TrialDurationplot               matlab.ui.control.UIAxes
        Lickplot                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SaveRoot % Description
        FileName
        rwrd_img
        Norwrd_img
        trials
        trial
        alpha
        img_len
        lwr_trsh
        hgh_trsh
        distToChange
        cntrst_dx
        rewardsize
        mqttip % Description
        ScreenNo % Description
        stopandsave = 0; % Description
        myMQTT
        s
        lickdistance = 0;
        distance
        RewardCount = 0;
        SubjM,
        Licks,
        Rewardposition,
        TrialDuration,
        Reward,
        RewardTrial_Strc,
        CatchTrial_Strc,
        NewTextureTrial_Strc,
        SelectedPath,
        New_rwrd_img,
        New_nrwrd_img,
        combinedSpeed
        u
        texturecategory
        Sessiontype
        onewindowacrossmonitors
        displacement
    end

    methods (Access = private)


        function SynchronizeUDP(app)
            global Timeline;
            global sess
            Timeline = [];

            x = instrfindall;
            for j =1:length(x)
                fclose(x(j)); % close all exist instance (UDP)
            end

            datexp = datestr(now, 'yyyy_mm_dd');
            fpath = fullfile(app.SelectedPath, app.AnimalIDEditField.Value, datexp);
            % global sampling rate
            srate = 5000;
            if app.RecordyesCheckBox.Value
                msg = 'instruction hello ';
                app.u = udp('10.102.20.144', 1002, 'LocalPort', 1001); % mesoscope
                fopen(app.u); % start udp
                fwrite(app.u, msg); %send message to mesoscope matlab
                pause(3);
            end
            daqreset; % rest NIdaq

            fld = dir(fpath);
            nmax = 0;
            for i = 3:numel(fld)
                if fld(i).isdir
                    nmax = max(nmax, str2num(fld(i).name));
                end
            end
            blockexp = sprintf('%d', nmax+1); % save as different files for diffrent blocks
            fdata= fullfile(fpath, blockexp);
            mkdir(fdata);
            fprintf('Block %s, of mouse %s, date %s \n', blockexp, app.AnimalIDEditField.Value, datexp);

            %NIdaq
            sess.sread = daq.createSession('ni'); % need to read information from NIdaq
            addAnalogInputChannel(sess.sread,'Dev4',1,'Voltage'); % read in the twop
            sess.sread.Rate = srate;
            sess.sread.IsContinuous = true;

            t   = 1;
            fr  = 50; % frequency of pulse generation (per second)
            NT = ceil(t*srate);
            fperiod = srate/fr;
            idx = [1:NT]';
            % generate this pulse
            wave = 5 * double(sin(pi + 2*pi*(idx/fperiod)) - .5 > 0);

            sess.readL = addlistener(sess.sread,'DataAvailable',@getTimeline);
            sess.sread.NotifyWhenDataAvailableExceeds = srate/10;  % update the screen at 50 Hz
            Timeline.data = zeros(5*sess.sread.Rate * 3600, 3);
            Timeline.numSamples = 0;
            Timeline.npacks = 0;
            sess.mname = app.AnimalIDEditField.Value;
            sess.datexp = datexp;
            sess.blockexp = blockexp;
            sess.fdata = fdata;
            % queueOutputData(sess.sgen,wave);
            startBackground(sess.sread); % start reading frames
            pause(0.25);


            % hard-coded paths and addresses for starting acquisition
            root_data = 'E:\DATA\'; % on the behavior rig or mesocope rig??????????????????????????
            fname = sprintf('%s_%s_%s', app.AnimalIDEditField.Value, datexp, blockexp);
            fpath = fullfile(root_data, app.AnimalIDEditField.Value, datexp, sprintf('%s', blockexp), fname);
            msg = sprintf('instruction ExpStart filepath %s', fpath);
            if app.RecordyesCheckBox.Value
                fwrite(app.u, msg); %send message to mesoscope matlab
                pause(2); % allow session to start
            end
        end

        function InitializeWithoutUDP(app)
            global Timeline sess
            Timeline = [];

            datexp = datestr(now, 'yyyy_mm_dd');
            fpath = fullfile(app.SelectedPath, app.AnimalIDEditField.Value, datexp);
            % global sampling rate
            srate = 5000;

            fld = dir(fpath);
            nmax = 0;
            for i = 3:numel(fld)
                if fld(i).isdir
                    nmax = max(nmax, str2num(fld(i).name));
                end
            end
            blockexp = sprintf('%d', nmax+1); % save as different files for diffrent blocks
            fdata= fullfile(fpath, blockexp);
            mkdir(fdata);
            fprintf('Block %s, of mouse %s, date %s \n', blockexp, app.AnimalIDEditField.Value, datexp);

            Timeline.data = zeros(5*srate * 3600, 3);
            Timeline.numSamples = 0;
            Timeline.npacks = 0;
            sess.mname = app.AnimalIDEditField.Value;
            sess.datexp = datexp;
            sess.blockexp = blockexp;
            sess.fdata = fdata;
        end

        function [Results, Settings] = AssingResults(app)

            Results.Movement = app.SubjM;
            Results.Licks = app.Licks;
            Results.RewardInfo = app.Rewardposition;
            Results.TrialRewardStrct = app.RewardTrial_Strc';
            Results.TrialNewTextureStrct = app.NewTextureTrial_Strc';
            Results.TrialCatchStrct = app.CatchTrial_Strc';
            Results.TrialDuration = app.TrialDuration';
            Results.displacement = app.displacement;

            Settings.NoTrials = app.NoTrialsEditField.Value;
            Settings.Session = app.Sessiontype;
            Settings.TextureCategory = app.texturecategory;
            Settings.RewardProb = app.PropRewardEditField.Value;
            Settings.NewRewardProb = app.PropNewEditField.Value;
            Settings.CatchProb = app.PropCatchEditField.Value;
            Settings.CorridorLength_dm = app.ImagelengthEditField.Value;
            Settings.ConstrastSteps = app.ContraststepsEditField.Value;
            Settings.HghVel = app.HigherEditField.Value;
            Settings.LwrVel = app.LowerEditField.Value;
            Settings.RewardSize = app.RewardSizeEditField.Value;

        end

        function Save(app)
            global Timeline sess
            [Results, Settings] = AssingResults(app);
            Timeline.Results = Results;
            Timeline.Settings = Settings;
            save(app.SaveRoot,"Timeline",'-v7.3');
            %% Saving in dm11
            rootZ = 'Z:\data\PROC';
            if exist(rootZ,"dir") == 7 %% dont ask me why matlab work in this way.....
                app.SavingLabel.Text = 'Saving dm11';
                pause(1/100)
                zdata = fullfile(rootZ, sess.mname, sess.datexp, sess.blockexp);
                mkdir(zdata);
                save(fullfile(zdata, sprintf('%s_%s_%s', sess.mname, sess.datexp, sess.blockexp)), 'Timeline', '-v7.3');
            end

        end

        function [RewardTrial_Strc, NewTextureTrial_Strc, CatchTrial_Strc] = CreateTrials(app)
            CatchTrial_Strc = zeros(1,app.trials);
            NewTextureTrial_Strc = zeros(1,app.trials);
            RewardTrial_Strc = [zeros(1,(1-app.PropRewardEditField.Value)*app.trials) ones(1,app.PropRewardEditField.Value*app.trials)];
            RewardTrial_Strc = RewardTrial_Strc(randperm(app.trials));
            n_new_trials = app.trials*(app.PropNewEditField.Value/2);
            zero_idx_rwdstrc = find(RewardTrial_Strc==0);
            ones_idx_rwdstrc = find(RewardTrial_Strc==1);
            idx_rwd_test = randsample(ones_idx_rwdstrc,n_new_trials,false);
            idx_nrwd_test = randsample(zero_idx_rwdstrc,n_new_trials,false);
            NewTextureTrial_Strc(1,idx_nrwd_test) = 1;
            NewTextureTrial_Strc(1,idx_rwd_test) = 1;
            for i=1:app.trials
                if RewardTrial_Strc(i) == 1 && NewTextureTrial_Strc(i) == 0
                    CatchTrial_Strc(i) = randsample([0 1],1,true,[(1-app.PropCatchEditField.Value) app.PropCatchEditField.Value]);
                end
            end
        end

       function cleanUPsess(app)
       global Timeline sess;
       
       % wait for the generation to stop
       if app.MesoscopeButton.Value
            delete(sess.readL);
            delete(sess.sread);
       end
       
       if app.BergamoButton.Value
          if ~isempty(Timeline.daq.pack)
            Timeline.daq.data = [Timeline.daq.pack.data];
            Timeline.daq.time = [Timeline.daq.pack.time];
            Timeline.daq.pack = [];
            delete(sess.readL);
            delete(sess.sread);
          end
       end

       Timeline.data = Timeline.data(1:Timeline.numSamples, :);
        
       xs = find(Timeline.data(2:end,1)>1 & Timeline.data(1:end-1,1)<1);
       fprintf('Detected %d video frames\n', numel(xs))
       
       ivalid = [0; cumsum(Timeline.data(2:end-1,2)>4)>0];
       xs = find(ivalid & Timeline.data(1:end-1,2)<2 & Timeline.data(2:end,2)>2);
       fprintf('Detected %d neural frames\n', numel(xs))
       
%        % save Timeline file
%        Timeline.mname = sess.mname;
%        Timeline.datexp = sess.datexp;
%        Timeline.blockexp = sess.blockexp;
%        
%        Timeline.img = [];
%        save(fullfile(sess.fdata, sprintf('Timeline_%s_%s_%s', sess.mname, sess.datexp, sess.blockexp)), 'Timeline');
        end
        function SynchronizeFacemap(app)
            global sess
            fpath = fullfile('D:\EXP', sess.mname, sess.datexp, sprintf('%s', sess.blockexp));
            fname = sprintf('%s_%s_%s', sess.mname, sess.datexp, sess.blockexp);
            publish(mqtt('tcp://127.0.0.1'), 'trigger', fullfile(fpath, fname));
        end   

        function SynchronizeUDPMiniscope(app)
            global Timeline;
            global sess
            Timeline = [];
        
            x = instrfindall;
            for j =1:length(x)
                if strcmp(x(j).type,'udp')
                    fclose(x(j)); % close all exist instance (UDP)
                end
            end
        
            datexp = datestr(now, 'yyyy_mm_dd');
            fpath = fullfile(app.SelectedPath, app.AnimalIDEditField.Value, datexp);
            % global sampling rate
            srate = 5000;
            if app.RecordyesCheckBox.Value
                msg = 'instruction hello ';
                app.u = udp('10.123.1.176', 1002, 'LocalPort', 1001); % mesoscope
                fopen(app.u); % start udp
                fwrite(app.u, msg); %send message to mesoscope matlab
                sess.fs      = str2double(char(fread(app.u)));
                sess.nplanes = str2double(char(fread(app.u)));
        
                if isnan(sess.fs)
                    error('Could not communicate with scanimage computer');
                end
                    pause(3);
            end
            daqreset; % rest NIdaq
            
            fld = dir(fpath);
            nmax = 0;
            for i = 3:numel(fld)
                if fld(i).isdir
                    nmax = max(nmax, str2num(fld(i).name));
                end
            end
            blockexp = sprintf('%d', nmax+1); % save as different files for diffrent blocks
            fdata= fullfile(fpath, blockexp);
            mkdir(fdata);
            fprintf('Block %s, of mouse %s, date %s \n', blockexp, app.AnimalIDEditField.Value, datexp);
        
            %NIdaq
            sess.sread = daq.createSession('ni'); % need to read information from NIdaq
            addAnalogInputChannel(sess.sread,'Dev4',0,'Voltage'); % read in the twop %%%changed to 0
            sess.sread.Rate = srate;
            sess.sread.IsContinuous = true;
        
            %t   = 1;
            %fr  = 50; % frequency of pulse generation (per second)
            %NT = ceil(t*srate);
            %fperiod = srate/fr;
            %idx = [1:NT]';
            % generate this pulse
            %wave = 5 * double(sin(pi + 2*pi*(idx/fperiod)) - .5 > 0);
            Timeline.daq.nframes = 0;
            Timeline.daq.last_mic = now;
            Timeline.daq.ipack = 0;
            Timeline.daq.pack = [];
            sess.readL = addlistener(sess.sread,'DataAvailable',@getTimelinemini);
            sess.sread.NotifyWhenDataAvailableExceeds = 80;  % update the screen at 50 Hz
            Timeline.data = zeros(5*sess.sread.Rate * 3600, 2);
            Timeline.numSamples = 0;
            Timeline.npacks = 0;
            sess.mname = app.AnimalIDEditField.Value;
            sess.datexp = datexp;
            sess.blockexp = blockexp;
            sess.fdata = fdata;
            % queueOutputData(sess.sgen,wave);
            startBackground(sess.sread); % start reading frames
            %disp('run with udp')
            pause(0.25);
        
        
            % hard-coded paths and addresses for starting acquisition
            root_data = 'D:\DATA\'; % 2p minirig
            fname = sprintf('%s_%s_%s', app.AnimalIDEditField.Value, datexp, blockexp);
            fpath = fullfile(root_data, app.AnimalIDEditField.Value, datexp, sprintf('%s', blockexp), fname);
            msg = sprintf('instruction ExpStart filepath %s', fpath);
            if app.RecordyesCheckBox.Value
                fwrite(app.u, msg); %send message to mesoscope matlab
                pause(20); % allow session to start
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DateLabelValue.Text = datestr(floor(now), 'yyyy_mm_dd') ;
            Screen('Preference', 'SkipSyncTests', 1);
            addpath('.\data')
            addpath('.\src')
            selectedButton = app.RoomButtonGroup.SelectedObject;
            switch selectedButton
                case app.MesoscopeButton
                    app.MesoscopeLabelDescrip.Text = 'Needs surrounding on Nvidia prior MATLAB opening';
                    app.onewindowacrossmonitors = false;
                    app.ScreenNo = 1;
                case app.TrainingButton
                    app.MesoscopeLabelDescrip.Text = '';
                    app.onewindowacrossmonitors = true;
                    app.ScreenNo = 3;
            end
            selectedButton = app.SessionButtonGroup.SelectedObject;
            switch selectedButton
                case app.SessionTrainingButton
                    app.Sessiontype = "Training";
                    app.PropRewardEditField.Value = 0.5;
                    app.PropCatchEditField.Value = 0;
                    app.PropNewEditField.Value = 0;
                    app.mqttip = 'tcp://169.254.0.10';
                    app.rewardtrainCheckBox.Value = true;
                    app.AutorewardtestCheckBox.Value = false; 
                    %app.ScreenNo = 3;
                case app.SessionNewexemplarButton
                    app.Sessiontype = "Generalisation";
                    app.PropRewardEditField.Value = 0.6;
                    app.PropCatchEditField.Value = 0;
                    app.PropNewEditField.Value = 0.3;
                    app.mqttip = 'tcp://169.254.0.10';
                    app.AutorewardtestCheckBox.Value = true; 
                    app.rewardtrainCheckBox.Value = false;
                    %app.ScreenNo = 3;
                case app.SessionNewexemplarcatchButton
                    app.Sessiontype = "Generalisation-Catch";
                    app.PropRewardEditField.Value = 0.5;
                    app.PropCatchEditField.Value = 0.1;
                    app.PropNewEditField.Value = 0.2;
                    app.mqttip = 'tcp://169.254.0.10';
                    %app.ScreenNo = 3;
                    app.AutorewardtestCheckBox.Value = true; 
                    app.rewardtrainCheckBox.Value = false;
            end
        end

        % Button pushed function: UploadTexturesButton
        function UploadTexturesButtonPushed(app, event)
            selectedButton = app.SelectTextureRNRButtonGroup.SelectedObject;
            switch selectedButton
                case app.LeavesCirclesButton
                    app.texturecategory = "leavescircles";
                case app.CirclesLeavesButton
                    app.texturecategory = "circlesleaves";
                case app.TilesSquaresButton
                    app.texturecategory = "tilessquares";
                case app.SquaresTilesButton
                    app.texturecategory = "squarestiles";
                case app.LeavesTilesButton
                    app.texturecategory = "leavestiles";
                case app.LeavesTilesButton
                    app.texturecategory = "tilesleaves";
                case app.GRAYButton
                    app.texturecategory = 'gray';
            end
            [app.rwrd_img,app.Norwrd_img,app.New_rwrd_img,app.New_nrwrd_img] = UploadImages(app.texturecategory);
            app.TexturesLamp.Color = 'g';
        end

        % Button pushed function: SetExperimentButton
        function SetExperimentButtonPushed(app, event)
            [app.trials,app.trial,app.alpha,app.img_len,app.lwr_trsh,app.hgh_trsh,app.distToChange,app.cntrst_dx] = ExperimentSetup(app.NoTrialsEditField.Value,...
                app.ImagelengthEditField.Value,app.ContraststepsEditField.Value,app.LowerEditField.Value,app.HigherEditField.Value);
            app.rewardsize = app.RewardSizeEditField.Value;
            app.Lickplot.XLim = [0 app.ImagelengthEditField.Value+10];
            app.Lickplot.YLim = [0 app.NoTrialsEditField.Value];
            app.TrialDurationplot.XLim = [0 app.NoTrialsEditField.Value];
            app.LampExperiment.Color = 'g';
        end

        % Button pushed function: SavedatatoButton
        function SavedatatoButtonPushed(app, event)
            app.SelectedPath = uigetdir('D:\EXP');
            app.SavePathLabel.Text = app.SelectedPath;
        end

        % Button pushed function: SetIDButton
        function SetIDButtonPushed(app, event)
            app.LampID.Color = 'g';
        end

        % Button pushed function: STOPSAVEButton
        function STOPSAVEButtonPushed(app, event)
            app.stopandsave = 1;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global sess
            
            [screenNumber,~,~,grey] = PsychInit(app.ScreenNo);

            if app.TrainingButton.Value
                offset = 1920;
                res = [2048*3 1542];
                InitializeWithoutUDP(app);
            elseif app.MesoscopeButton.Value
                res = NaN(1,2);
                SynchronizeUDP(app);
                %SynchronizeFacemap(app)
            else
                res = NaN(1,2);
                SynchronizeUDPMiniscope(app)
                %InitializeWithoutUDP(app);
            end

            app.FileName = strcat(app.AnimalIDEditField.Value,'_',app.DateLabelValue.Text,'_',sess.blockexp,'.mat');
            app.SaveRoot = fullfile(sess.fdata,app.FileName);

            [app.rwrd_img,app.Norwrd_img,app.New_rwrd_img,app.New_nrwrd_img] = ResizeNGreyCentering(app.rwrd_img,app.Norwrd_img,app.New_rwrd_img,app.New_nrwrd_img,grey,screenNumber,app.onewindowacrossmonitors,res);

            %% MQTT Sub
            %[app.myMQTT,MoveSub,MoveMsgCount,LickSub,LickMsgCount] = MqttSub(app.mqttip);
            %% Cylinder Board init
            app.s = serialport("COM4",9600);% can check the serialport number from device manager and change the name accordingly COM4
            flush(app.s);
            configureTerminator(app.s,"CR");
            fopen(app.s);
            fprintf(app.s,'thr 20'); %% sets the treshold for the lickport
            %status = strtrim(fscanf(app.s));
            fprintf(app.s, 'START');
            %% Init variables.
            AlreadyGiven = 0;
            totaltime(1) = 0;
            app.Rewardposition = NaN(app.trials,2);
            [app.RewardTrial_Strc, app.NewTextureTrial_Strc, app.CatchTrial_Strc] = CreateTrials(app);
            %timeflush = 200;
            Intertrial_distance = 10;
            LWR_BOUND_REWARD_ZONE = app.img_len/2;
            HIGH_BOUND_REWARD_ZONE = (app.img_len/2) + Intertrial_distance;
            RandomRwrdPos = (HIGH_BOUND_REWARD_ZONE-LWR_BOUND_REWARD_ZONE).*rand(1,1) + LWR_BOUND_REWARD_ZONE;
            UM_WATER_OBJ = (app.RewardObjVal.Value*1000)/(app.NoTrialsEditField.Value*app.PropRewardEditField.Value);
            %% Experiment
            try
                %% Texture making
                PsychImaging('PrepareConfiguration');
                PsychImaging('AddTask', 'General', 'FloatingPoint32BitIfPossible');
                if app.onewindowacrossmonitors
                    [win, ~] = PsychImaging('OpenWindow',screenNumber,grey,[offset 0 offset+res(1) res(2)]);
                    % rectsize =[top-left-x top-left-y bottom-right-x bottom-left-y]
                else
                    [win, ~] = PsychImaging('OpenWindow',screenNumber,grey);
                end
                Screen('ColorRange', win, 1, 0);
                imagtex_reward = Screen('MakeTexture', win, app.rwrd_img , [], [], 1);
                imagtex_non_reward = Screen('MakeTexture', win, app.Norwrd_img , [], [], 1);
                imagtex_new_reward = Screen('MakeTexture', win, app.New_rwrd_img , [], [], 1);
                imagtex_new_nreward = Screen('MakeTexture', win, app.New_nrwrd_img , [], [], 1);
                %% Random texture selection
                [texture_toshow] = RandomImgSelection(imagtex_reward,imagtex_non_reward,imagtex_new_reward,imagtex_new_nreward,app.RewardTrial_Strc(app.trial),app.NewTextureTrial_Strc(app.trial));
                %% Experiment init
                [app.distance,app.SubjM,app.Licks] = ExperimentInit(app.trial,app.alpha,app.RewardTrial_Strc(app.trial));
                app.displacement = [app.trial 0 0 now];
                %% Experiment run
                t0 = tic;
                while app.trial<=app.trials
                    [a,b,keyCode] = KbCheck;
	                if keyCode(120)>0
		                break;
	                end
                    %% Monitoring (label changing)
                    app.AutorewardRNDLabel.Text = num2str(RandomRwrdPos);
                    app.CurrentRewardml.Text = num2str(app.RewardCount);
                    app.CurrentTrial.Text = num2str(app.trial);
                    app.WaterDelivered.Text = num2str(UM_WATER_OBJ * app.RewardCount);
                    if app.distance > app.img_len
                        app.IsImageRewarded.Text = 'Intertrial';
                    elseif app.RewardTrial_Strc(app.trial) &&  app.CatchTrial_Strc(app.trial)
                        app.IsImageRewarded.Text = 'Rewarded (Catch)';
                    elseif app.RewardTrial_Strc(app.trial) &&  app.NewTextureTrial_Strc(app.trial)
                        app.IsImageRewarded.Text = 'Rewarded (New)';
                    elseif ~app.RewardTrial_Strc(app.trial) &&  app.NewTextureTrial_Strc(app.trial)
                        app.IsImageRewarded.Text = 'Non Rewarded (New)';                        
                    elseif app.RewardTrial_Strc(app.trial)
                        app.IsImageRewarded.Text = 'Rewarded';
                    else
                        app.IsImageRewarded.Text = 'Non Rewarded';
                    end
                    
                    %% MQTT Flush each 200 secs
%                     if toc(t0) > timeflush
%                         clear app.myMQTT
%                         [app.myMQTT,MoveSub,MoveMsgCount,LickSub,LickMsgCount] = MqttSub(app.mqttip);
%                         timeflush = timeflush + 200;
%                     end
                    %% Stop button & stop if time > 60 min
                    if app.stopandsave
                        break;
                    elseif app.Autostop60minCheckBox.Value
                        if ((totaltime(end)/60) > 60)
                            break;
                        end
                    end
                    %% Modify Texture
                    Screen('Blendfunction', win, GL_SRC_ALPHA, GL_ONE);
                    Screen('DrawTexture', win, texture_toshow, [], [], [], 1, app.alpha);
                    Screen('DrawingFinished', win);
                    %% Read movement
                    %pause(2/1000);
                    %[app.SubjM,MoveMsgCount] = ReadMov(app.SubjM,MoveSub,MoveMsgCount,app.distance,app.trial);
                    %app.combinedSpeed = sum(app.SubjM(end,1:2).^2)^0.5;

                    %% Read licks
                    %pause(2/1000);
                    %[app.Licks, LickMsgCount, IsLicking] = ReadLick(app.Licks,LickSub,LickMsgCount,app.trial,app.distance,app.alpha,app.RewardTrial_Strc(app.trial));
                    %% Read Sensors
                    %pause(2/1000);
                    [~,d,speed,lickport] = ReadSensors(app.s);
                    speed = speed/100; % converting to decimeters
                    %% Automovement
                    if app.AutomovementCheckBox.Value
                        app.combinedSpeed = 0.71; %min speed to pass trsh
                        app.SubjM(end+1,:) = [app.combinedSpeed app.combinedSpeed d app.distance app.trial now];
                    else
                        app.SubjM(end+1,:) = [speed speed d app.distance app.trial now]; %keeping consistency with ball version
                        if app.SubjM(end,1)>0
                            app.combinedSpeed = 0; % only moves when cylider is going forward 
                        else
                            app.combinedSpeed = abs(app.SubjM(end,1));
                        end
                    end
                    
                    %% format lick and movement variables
                    Isrewarded = app.RewardTrial_Strc(app.trial);
                    [app.Licks, IsLicking] = ReadLick(app.Licks,app.trial,app.distance,app.lickdistance,app.alpha,Isrewarded,lickport);
                    %% Reward delivery
                    if app.distance>=LWR_BOUND_REWARD_ZONE && app.distance<HIGH_BOUND_REWARD_ZONE
                        if IsLicking && app.RewardTrial_Strc(app.trial) && ~AlreadyGiven && ~app.CatchTrial_Strc(app.trial) 
                            if app.RewardnewtextureCheckBox.Value && app.NewTextureTrial_Strc(app.trial)
                                [app.RewardCount] = DeliverReward(app.s,app.RewardCount,app.rewardsize);
                                app.Rewardposition(app.trial,:) = [app.lickdistance now];
                                AlreadyGiven = 1;
                            elseif ~app.NewTextureTrial_Strc(app.trial)
                                [app.RewardCount] = DeliverReward(app.s,app.RewardCount,app.rewardsize);
                                app.Rewardposition(app.trial,:) = [app.lickdistance now];
                                AlreadyGiven = 1;
                            end
                        end
                    end
                    if app.distance>=RandomRwrdPos && app.rewardtrainCheckBox.Value
                    %% Random Autoreward delivery
                        if app.RewardTrial_Strc(app.trial) && ~AlreadyGiven && ~app.CatchTrial_Strc(app.trial) 
                            if app.RewardnewtextureCheckBox.Value && app.NewTextureTrial_Strc(app.trial)
                                [app.RewardCount] = DeliverReward(app.s,app.RewardCount,app.rewardsize);
                                app.Rewardposition(app.trial,:) = [app.lickdistance now];
                                AlreadyGiven = 1;
                            elseif ~app.NewTextureTrial_Strc(app.trial)
                                [app.RewardCount] = DeliverReward(app.s,app.RewardCount,app.rewardsize);
                                app.Rewardposition(app.trial,:) = [app.lickdistance now];
                                AlreadyGiven = 1;
                            end
                        end
                    elseif app.distance>=HIGH_BOUND_REWARD_ZONE && app.AutorewardtestCheckBox.Value
                        %% Autoreward at the end of reward region delivery
                        if app.RewardTrial_Strc(app.trial) && ~AlreadyGiven && ~app.CatchTrial_Strc(app.trial) 
                            if app.RewardnewtextureCheckBox.Value && app.NewTextureTrial_Strc(app.trial)
                                [app.RewardCount] = DeliverReward(app.s,app.RewardCount,app.rewardsize);
                                app.Rewardposition(app.trial,:) = [app.lickdistance now];
                                AlreadyGiven = 1;
                            elseif ~app.NewTextureTrial_Strc(app.trial)
                                [app.RewardCount] = DeliverReward(app.s,app.RewardCount,app.rewardsize);
                                app.Rewardposition(app.trial,:) = [app.lickdistance now];
                                AlreadyGiven = 1;
                            end
                        end
                   end
                    
                    %% Change contrast (alpha) and show it to screen
                    if app.combinedSpeed > app.lwr_trsh && app.combinedSpeed < app.hgh_trsh % only change contrasts if inside tresholds
                        d_increment = app.dDistanceSpinner.Value;
                        app.lickdistance = app.lickdistance + app.combinedSpeed; %distance acumulation for lickport
                        app.distance = app.distance + d_increment; %distance accumulation at avg. speed
                        app.displacement(end+1,:) = [app.trial app.lickdistance app.distance now];
                        app.CurrentDistance.Text = num2str(app.distance,4);
                        steps = app.distance/app.distToChange; % distance traveled is enough to change to next contrast step
                        if app.distance <= (app.img_len/2)
                            %% Increase contrast
                            app.alpha = min(1, steps*app.cntrst_dx);
                        elseif app.distance > (app.img_len/2) && app.distance <= app.img_len 
                            %% Decrease contrast
                            app.alpha = max(0, 1-abs(1-steps*app.cntrst_dx));
                        elseif app.distance > app.img_len && app.distance <= app.img_len + Intertrial_distance
                            %% intertrial distance
                            app.alpha = 0;
                        elseif app.distance > app.img_len + Intertrial_distance
                            %% Trial reset
                            app.TrialDuration(app.trial) = toc(t0);
                            stem(app.TrialDurationplot, app.TrialDuration)
                            drawnow;
                            totaltime = cumsum(app.TrialDuration);
                            app.TotalElapsedTimeVal.Text = num2str(totaltime(end)/60);
                            dist_idx = (app.displacement(:,1)==app.trial);
                            lick_idx = (app.Licks(:,1) == app.trial);
                            trial_dist_table = app.displacement(dist_idx,2:3);
                            licks_correction = interp1(trial_dist_table(:,1),trial_dist_table(:,2),app.Licks(lick_idx,2));
                            app.Licks(lick_idx,2) = licks_correction;
                            if ~isnan(app.Rewardposition(app.trial,1))
                                r_pos = app.Rewardposition(app.trial,1);
                                reward_pos_corrected = interp1(trial_dist_table(:,1),trial_dist_table(:,2),r_pos);
                                app.Rewardposition(app.trial,1) = reward_pos_corrected;
                            end
                            %% Lickplot
                            if Isrewarded
                                scatter(app.Lickplot,app.Licks(lick_idx,2),app.Licks(lick_idx,1),7,"blue","|");
                                hold(app.Lickplot,"on");
                            else
                                scatter(app.Lickplot,app.Licks(lick_idx,2),app.Licks(lick_idx,1),7,"red","|");
                                hold(app.Lickplot,"on");
                            end
                            scatter(app.Lickplot,app.Rewardposition(app.trial,1),app.trial,30,'green',"V")
                            hold(app.Lickplot,"on");
                            %% Intertrial distance
                            %InterTrialDistance(MoveSub, MoveMsgCount, app.ImagelengthEditField,app.Intertrialdistancelabel, app.lwr_trsh, app.hgh_trsh);
                            %% Mqtt flush
                            %clear app.myMQTT
                            %[app.myMQTT,MoveSub,MoveMsgCount,LickSub,LickMsgCount] = MqttSub(app.mqttip);
                            %% New trial startup
                            %timeflush = 200;
                            app.alpha = 0;
                            app.distance = 0;
                            app.lickdistance = 0;
                            AlreadyGiven = 0;
                            RandomRwrdPos = (HIGH_BOUND_REWARD_ZONE-LWR_BOUND_REWARD_ZONE).*rand(1,1) + LWR_BOUND_REWARD_ZONE;
                            app.trial = app.trial + 1;
                            if app.trial <= app.trials
                                [texture_toshow] = RandomImgSelection(imagtex_reward,imagtex_non_reward,imagtex_new_reward,imagtex_new_nreward,app.RewardTrial_Strc(app.trial),app.NewTextureTrial_Strc(app.trial));
                                app.SubjM(end+1,:) = [0 0 0 app.distance app.trial now];
                                app.Licks(end+1,:) = [app.trial app.distance app.alpha app.RewardTrial_Strc(app.trial) now 1 app.lickdistance];
                                app.displacement(end+1,:) = [app.trial 0 0 now];
                            end
                            t0 = tic;
                        end
                        Screen('Flip', win);
                    end
                end
                %% Closing everything
                app.SavingLabel.Text = 'Saving...';
                pause(1/100)
                Screen('CloseAll');
                if app.RecordyesCheckBox.Value
                 fwrite(app.u, 'instruction ExpEnd'); % send message to end experiment
                 fclose(app.u);
                end
                pause(1);
                if app.MesoscopeButton.Value
                    %SynchronizeFacemap(app)
                end
                if ~app.TrainingButton.Value
                    stop(sess.sread);
                    pause(1) 
                end
                cleanUPsess(app)
                Save(app)
                app.SavingLabel.Text = 'Saved!';
                clear s
                sca;
                return;
   %            catch
                sca;
                psychrethrow(psychlasterror);
            end
        end

        % Button pushed function: ManualRewardButton
        function ManualRewardButtonPushed(app, event)
            [app.RewardCount] = DeliverReward(app.s,app.RewardCount,app.rewardsize);
        end

        % Selection changed function: SessionButtonGroup
        function SessionButtonGroupSelectionChanged(app, event)
            selectedButton = app.SessionButtonGroup.SelectedObject;
            switch selectedButton
                case app.SessionTrainingButton
                    app.Sessiontype = "Training";
                    app.PropRewardEditField.Value = 0.5;
                    app.PropCatchEditField.Value = 0;
                    app.PropNewEditField.Value = 0;
                    app.mqttip = 'tcp://169.254.0.10';
                    app.rewardtrainCheckBox.Value = true;
                    app.AutorewardtestCheckBox.Value = false; 
                    %app.ScreenNo = 3;
                case app.SessionNewexemplarButton
                    app.Sessiontype = "Generalisation";
                    app.PropRewardEditField.Value = 0.6;
                    app.PropCatchEditField.Value = 0;
                    app.PropNewEditField.Value = 0.3;
                    app.mqttip = 'tcp://169.254.0.10';
                    app.AutorewardtestCheckBox.Value = true; 
                    app.rewardtrainCheckBox.Value = false;
                    %app.ScreenNo = 3;
                case app.SessionNewexemplarcatchButton
                    app.Sessiontype = "Generalisation-Catch";
                    app.PropRewardEditField.Value = 0.5;
                    app.PropCatchEditField.Value = 0.1;
                    app.PropNewEditField.Value = 0.2;
                    app.mqttip = 'tcp://169.254.0.10';
                    %app.ScreenNo = 3;
                    app.AutorewardtestCheckBox.Value = true; 
                    app.rewardtrainCheckBox.Value = false;
            end
        end

        % Selection changed function: RoomButtonGroup
        function RoomButtonGroupSelectionChanged(app, event)
            selectedButton = app.RoomButtonGroup.SelectedObject;
            switch selectedButton
                case app.MesoscopeButton
                    app.MesoscopeLabelDescrip.Text = 'Needs surrounding on Nvidia prior MATLAB opening';
                    app.onewindowacrossmonitors = false;
                    app.ScreenNo = 3;
                case app.BergamoButton
                    app.MesoscopeLabelDescrip.Text = 'Needs surrounding on Nvidia prior MATLAB opening';
                    app.onewindowacrossmonitors = false;
                    app.ScreenNo = 2;
                case app.TrainingButton
                    app.MesoscopeLabelDescrip.Text = '';
                    app.onewindowacrossmonitors = true;
                    app.ScreenNo = 3; 
            end
        end

        % Selection changed function: SelectTextureRNRButtonGroup
        function SelectTextureRNRButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectTextureRNRButtonGroup.SelectedObject;
            switch selectedButton
                case app.LeavesCirclesButton
                    app.texturecategory = "leavescircles";
                case app.CirclesLeavesButton
                    app.texturecategory = "circlesleaves";
                case app.TilesSquaresButton
                    app.texturecategory = "tilessquares";
                case app.SquaresTilesButton
                    app.texturecategory = "squarestiles";
                case app.LeavesTilesButton
                    app.texturecategory = "leavestiles";
                case app.LeavesTilesButton
                    app.texturecategory = "tilesleaves";
                case app.GRAYButton
                    app.texturecategory = 'gray';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContrastChangeCorridorUIFigure = uifigure('Visible', 'off');
            app.ContrastChangeCorridorUIFigure.Name = 'Contrast Change Corridor';
            app.Lickplot = uiaxes(app.ContrastChangeCorridorUIFigure);
            title(app.Lickplot, 'Licks')
            xlabel(app.Lickplot, 'Position')
            ylabel(app.Lickplot, 'Trial #')
            app.Lickplot.XLim = [0 1];
            app.TrialDurationplot = uiaxes(app.ContrastChangeCorridorUIFigure);
            title(app.TrialDurationplot, 'Trial Duration')
            xlabel(app.TrialDurationplot, 'Trial #')
            ylabel(app.TrialDurationplot, 'Time (s)')
            app.STARTButton = uibutton(app.ContrastChangeCorridorUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPSAVEButton = uibutton(app.ContrastChangeCorridorUIFigure, 'push');
            app.STOPSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSAVEButtonPushed, true);
            app.STOPSAVEButton.Text = 'STOP/SAVE';
            app.SavePathValueLabel = uilabel(app.ContrastChangeCorridorUIFigure);
            app.SavePathValueLabel.Text = '';
            app.ExperimentSetupPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.ExperimentSetupPanel.Title = '3) Experiment Setup';
            app.VelocityTresholdsLabel = uilabel(app.ExperimentSetupPanel);
            app.VelocityTresholdsLabel.Text = 'Velocity Tresholds';
            app.LowerEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.LowerEditFieldLabel.Text = 'Lower ';
            app.LowerEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.LowerEditField.Value = 0.7;
            app.HigherEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.HigherEditFieldLabel.Text = 'Higher ';
            app.HigherEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.HigherEditField.Value = 20;
            app.SetExperimentButton = uibutton(app.ExperimentSetupPanel, 'push');
            app.SetExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SetExperimentButtonPushed, true);
            app.SetExperimentButton.Text = 'Set';
            app.LampExperiment = uilamp(app.ExperimentSetupPanel);
            app.RewardLabel = uilabel(app.ExperimentSetupPanel);
            app.RewardLabel.Text = 'Reward';
            app.SizemsLabel = uilabel(app.ExperimentSetupPanel);
            app.SizemsLabel.Text = 'Size (ms)';
            app.RewardSizeEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.RewardSizeEditField.Value = 69;
            app.PropRewardEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.PropRewardEditFieldLabel.Text = 'Prop. Reward';
            app.PropRewardEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.PropRewardEditField.Value = 0.5;
            app.PropCatchEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.PropCatchEditFieldLabel.Text = 'Prop. Catch';
            app.PropCatchEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.PropNewEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.PropNewEditFieldLabel.Text = 'Prop. New ';
            app.PropNewEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.NoTrialsEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.NoTrialsEditFieldLabel.Text = 'No. Trials';
            app.NoTrialsEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.NoTrialsEditField.Value = 400;
            app.ImagelengthLabel = uilabel(app.ExperimentSetupPanel);
            app.ImagelengthLabel.Text = 'Image length ';
            app.ImagelengthEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.ImagelengthEditField.Value = 30;
            app.ContraststepsEditFieldLabel = uilabel(app.ExperimentSetupPanel);
            app.ContraststepsEditFieldLabel.Text = 'Contrast steps';
            app.ContraststepsEditField = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.ContraststepsEditField.Value = 30;
            app.RewardObjlabel = uilabel(app.ExperimentSetupPanel);
            app.RewardObjlabel.Text = 'Reward obj (ml)';
            app.RewardObjVal = uieditfield(app.ExperimentSetupPanel, 'numeric');
            app.RewardObjVal.Value = 0.7;
            app.AnimalInformationPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.AnimalInformationPanel.Title = '1) Animal Information';
            app.AnimalIDEditFieldLabel = uilabel(app.AnimalInformationPanel);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.AnimalInformationPanel, 'text');
            app.DateLabel = uilabel(app.AnimalInformationPanel);
            app.DateLabel.Text = 'Date:';
            app.DateLabelValue = uilabel(app.AnimalInformationPanel);
            app.DateLabelValue.Text = '';
            app.SavedatatoButton = uibutton(app.AnimalInformationPanel, 'push');
            app.SavedatatoButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatoButtonPushed, true);
            app.SavedatatoButton.Text = 'Save data to:';
            app.SetIDButton = uibutton(app.AnimalInformationPanel, 'push');
            app.SetIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetIDButtonPushed, true);
            app.SetIDButton.Text = 'Set';
            app.SavePathLabel = uilabel(app.AnimalInformationPanel);
            app.SavePathLabel.Text = '';
            app.LampID = uilamp(app.AnimalInformationPanel);
            app.TrainingPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.TrainingPanel.Title = 'Training';
            app.ManualRewardButton = uibutton(app.TrainingPanel, 'push');
            app.ManualRewardButton.ButtonPushedFcn = createCallbackFcn(app, @ManualRewardButtonPushed, true);
            app.ManualRewardButton.Text = 'Manual Reward';
            app.AutorewardtestCheckBox = uicheckbox(app.TrainingPanel);
            app.AutorewardtestCheckBox.Text = {'Auto reward test'; ''};
            app.AutomovementCheckBox = uicheckbox(app.TrainingPanel);
            app.AutomovementCheckBox.Text = 'Auto movement';
            app.Autostop60minCheckBox = uicheckbox(app.TrainingPanel);
            app.Autostop60minCheckBox.Text = 'Auto stop (60 min)';
            app.RewardnewtextureCheckBox = uicheckbox(app.TrainingPanel);
            app.RewardnewtextureCheckBox.Text = 'Reward new texture';
            app.rewardtrainCheckBox = uicheckbox(app.TrainingPanel);
            app.rewardtrainCheckBox.Text = {'Auto reward training'; ''};
            app.AutorewardRNDLabel = uilabel(app.TrainingPanel);
            app.AutorewardRNDLabel.Text = '';
            app.dDistanceSpinnerLabel = uilabel(app.TrainingPanel);
            app.dDistanceSpinnerLabel.Text = 'dDistance';
            app.dDistanceSpinner = uispinner(app.TrainingPanel);
            app.dDistanceSpinner.Step = 0.002;
            app.dDistanceSpinner.Limits = [0.02 10];
            app.dDistanceSpinner.Value = 0.035;
            app.MonitoringPanel = uipanel(app.ContrastChangeCorridorUIFigure);
            app.MonitoringPanel.Title = 'Monitoring';
            app.CurrentpositionLabel = uilabel(app.MonitoringPanel);
            app.CurrentpositionLabel.Text = 'Current position:';
            app.CurrentDistance = uilabel(app.MonitoringPanel);
            app.CurrentDistance.Text = {''; ''};
            app.RewardsgivenLabel = uilabel(app.MonitoringPanel);
            app.RewardsgivenLabel.Text = 'Rewards given:';
            app.CurrentRewardml = uilabel(app.MonitoringPanel);
            app.CurrentRewardml.Text = {''; ''};
            app.ImageLabel = uilabel(app.MonitoringPanel);
            app.ImageLabel.Text = 'Image:';
            app.IsImageRewarded = uilabel(app.MonitoringPanel);
            app.IsImageRewarded.Text = {''; ''};
            app.TrialLabel = uilabel(app.MonitoringPanel);
            app.TrialLabel.Text = 'Trial:';
            app.CurrentTrial = uilabel(app.MonitoringPanel);
            app.CurrentTrial.Text = {''; ''};
            app.TotalelapsedtimeLabel = uilabel(app.MonitoringPanel);
            app.TotalelapsedtimeLabel.Text = 'Total elapsed time:';
            app.TotalElapsedTimeVal = uilabel(app.MonitoringPanel);
            app.TotalElapsedTimeVal.Text = {''; ''};
            app.WaterdeliveredLabel = uilabel(app.MonitoringPanel);
            app.WaterdeliveredLabel.Text = {'Water delivered:'; ''; ''};
            app.WaterDelivered = uilabel(app.MonitoringPanel);
            app.WaterDelivered.Text = '';
            app.RoomButtonGroup = uibuttongroup(app.ContrastChangeCorridorUIFigure);
            app.RoomButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RoomButtonGroupSelectionChanged, true);
            app.RoomButtonGroup.Title = '4) Room';
            app.TrainingButton = uiradiobutton(app.RoomButtonGroup);
            app.TrainingButton.Text = 'Training';
            app.TrainingButton.Value = true;
            app.MesoscopeButton = uiradiobutton(app.RoomButtonGroup);
            app.MesoscopeButton.Text = 'Mesoscope';
            app.RecordyesCheckBox = uicheckbox(app.RoomButtonGroup);
            app.RecordyesCheckBox.Text = 'Record?';
            app.MesoscopeLabelDescrip = uilabel(app.RoomButtonGroup);
            app.MesoscopeLabelDescrip.Text = '';
            app.BergamoButton = uiradiobutton(app.RoomButtonGroup);
            app.BergamoButton.Text = 'Bergamo';
            app.SessionButtonGroup = uibuttongroup(app.ContrastChangeCorridorUIFigure);
            app.SessionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SessionButtonGroupSelectionChanged, true);
            app.SessionButtonGroup.Title = '2) Session';
            app.SessionTrainingButton = uiradiobutton(app.SessionButtonGroup);
            app.SessionTrainingButton.Text = 'Training';
            app.SessionTrainingButton.Value = true;
            app.SessionNewexemplarButton = uiradiobutton(app.SessionButtonGroup);
            app.SessionNewexemplarButton.Text = 'New exemplar ';
            app.SessionNewexemplarcatchButton = uiradiobutton(app.SessionButtonGroup);
            app.SessionNewexemplarcatchButton.Text = 'New exemplar & catch';
            app.SavingLabel = uilabel(app.ContrastChangeCorridorUIFigure);
            app.SavingLabel.Text = '';
            app.SelectTextureRNRButtonGroup = uibuttongroup(app.ContrastChangeCorridorUIFigure);
            app.SelectTextureRNRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectTextureRNRButtonGroupSelectionChanged, true);
            app.SelectTextureRNRButtonGroup.Title = '5) Select Texture (R/NR)';
            app.UploadTexturesButton = uibutton(app.SelectTextureRNRButtonGroup, 'push');
            app.UploadTexturesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTexturesButtonPushed, true);
            app.UploadTexturesButton.Text = '6) Upload Textures';
            app.LeavesCirclesButton = uiradiobutton(app.SelectTextureRNRButtonGroup);
            app.LeavesCirclesButton.Text = 'Leaves/Circles';
            app.LeavesCirclesButton.Value = true;
            app.TilesSquaresButton = uiradiobutton(app.SelectTextureRNRButtonGroup);
            app.TilesSquaresButton.Text = 'Tiles/Squares';
            app.CirclesLeavesButton = uiradiobutton(app.SelectTextureRNRButtonGroup);
            app.CirclesLeavesButton.Text = 'Circles/Leaves';
            app.SquaresTilesButton = uiradiobutton(app.SelectTextureRNRButtonGroup);
            app.SquaresTilesButton.Text = 'Squares/Tiles';
            app.LeavesTilesButton = uiradiobutton(app.SelectTextureRNRButtonGroup);
            app.LeavesTilesButton.Text = 'Leaves/Tiles';
            app.TilesLeavesButton = uiradiobutton(app.SelectTextureRNRButtonGroup);
            app.TilesLeavesButton.Text = 'Tiles/Leaves';
            app.GRAYButton = uiradiobutton(app.SelectTextureRNRButtonGroup);
            app.GRAYButton.Text = 'GRAY';
            app.TexturesLamp = uilamp(app.SelectTextureRNRButtonGroup);
            % Show the figure after all components are created
            app.ContrastChangeCorridorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContrastChangeCylinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ContrastChangeCorridorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContrastChangeCorridorUIFigure)
        end
    end
end